classdef musicAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        FileLabel         matlab.ui.control.Label
        FileField         matlab.ui.control.EditField
        FileSubmit        matlab.ui.control.Button
        Step1Label        matlab.ui.control.Label
        Step2Label        matlab.ui.control.Label
        Step3Label        matlab.ui.control.Label
        ButtonGroup       matlab.ui.container.ButtonGroup
        song1             matlab.ui.control.RadioButton
        song2             matlab.ui.control.RadioButton
        song3             matlab.ui.control.RadioButton
        Listen            matlab.ui.control.Button
        Step2Description  matlab.ui.control.Label
        Step2Status       matlab.ui.control.Label
        Step2Status1      matlab.ui.control.Label
        Step2Status2      matlab.ui.control.Label
        Step2Yes          matlab.ui.control.Button
        Step2No           matlab.ui.control.Button
        Step2Replay       matlab.ui.control.Button
        Label             matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        HzLabel           matlab.ui.control.Label
        Step2Status0      matlab.ui.control.Label
    end

    
    properties (Access = private)
        FREQ_INFO % save the base frequencies and amplitudes
    end
    
    properties (Access = public)
        decision % Description
    end
    
    
    methods (Access = public)
        function setOK(app)
            app.Step2Status0.Visible = 'off';
            app.Step2Status1.Visible = 'off';
            app.Step2Status2.Visible = 'on';
        end
        function setStart(app)
            app.Step2Status0.Visible = 'off';
            app.Step2Status1.Visible = 'on';
            app.Step2Status2.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Step2Status0.Visible = 'on';
            app.Step2Status1.Visible = 'off';
            app.Step2Status2.Visible = 'off';
            app.Step2Yes.Enable = 'off';
            app.Step2No.Enable = 'off';
            app.Step2Replay.Enable = 'off';
            app.Listen.Enable = 'off';
            app.decision = 2;
        end

        % Button pushed function: FileSubmit
        function FileSubmitPushed(app, event)
            file = app.FileField.Value;
            app.Step2Yes.Enable = 'on';
            app.Step2No.Enable = 'on';
            app.Step2Replay.Enable = 'on';
            app.FREQ_INFO = musicAnalyze(app, file);
            app.Step2Yes.Enable = 'off';
            app.Step2No.Enable = 'off';
            app.Step2Replay.Enable = 'off';
            app.Listen.Enable = 'on';
        end

        % Button pushed function: Step2Yes
        function Step2YesButtonPushed(app, event)
            app.decision = 1;
             uiresume(app.UIFigure);
        end

        % Button pushed function: Step2No
        function Step2NoButtonPushed(app, event)
            app.decision = 0;
             uiresume(app.UIFigure);
        end

        % Button pushed function: Step2Replay
        function Step2ReplayButtonPushed(app, event)
            app.decision = 2;
            uiresume(app.UIFigure);
        end

        % Button pushed function: Listen
        function ListenButtonPushed(app, event)
            fs = app.EditField.Value;
            if(app.song1.Value)
                makeWulingSong(fs, app.FREQ_INFO);
            elseif (app.song2.Value)
                makeTsinghuaSong(fs, app.FREQ_INFO);
            elseif (app.song3.Value)
                makeTsinghuaSong(fs, app.FREQ_INFO);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = {'輸入欲參照之檔案名稱'; '（請將其存在與本程式相同之目錄下）'};
            app.FileField = uieditfield(app.UIFigure, 'text');
            app.FileSubmit = uibutton(app.UIFigure, 'push');
            app.FileSubmit.ButtonPushedFcn = createCallbackFcn(app, @FileSubmitPushed, true);
            app.FileSubmit.Text = '分析！';
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step1.輸入欲模仿之音樂檔';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step2.鑑別自動分段結果';
            app.Step3Label = uilabel(app.UIFigure);
            app.Step3Label.Text = 'Step3.合成新音樂';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.song1 = uiradiobutton(app.ButtonGroup);
            app.song1.Text = '合成《武陵高中校歌》';
            app.song1.Value = true;
            app.song2 = uiradiobutton(app.ButtonGroup);
            app.song2.Text = '合成《清華大學校歌》';
            app.song3 = uiradiobutton(app.ButtonGroup);
            app.song3.Text = '合成《中華民國國旗歌》';
            app.Listen = uibutton(app.UIFigure, 'push');
            app.Listen.ButtonPushedFcn = createCallbackFcn(app, @ListenButtonPushed, true);
            app.Listen.Text = '給我聽！';
            app.Step2Description = uilabel(app.UIFigure);
            app.Step2Description.Text = '本步驟將請您以人耳鑑別自動分段後之各段是否僅含有「單音」，而不含其他顫音。';
            app.Step2Status = uilabel(app.UIFigure);
            app.Step2Status.Text = '狀態：';
            app.Step2Status1 = uilabel(app.UIFigure);
            app.Step2Status1.Visible = 'off';
            app.Step2Status1.Text = '尚未鑑別完畢';
            app.Step2Status2 = uilabel(app.UIFigure);
            app.Step2Status2.Visible = 'off';
            app.Step2Status2.Text = '已鑑別完畢';
            app.Step2Yes = uibutton(app.UIFigure, 'push');
            app.Step2Yes.ButtonPushedFcn = createCallbackFcn(app, @Step2YesButtonPushed, true);
            app.Step2Yes.Text = '可';
            app.Step2No = uibutton(app.UIFigure, 'push');
            app.Step2No.ButtonPushedFcn = createCallbackFcn(app, @Step2NoButtonPushed, true);
            app.Step2No.Text = '否';
            app.Step2Replay = uibutton(app.UIFigure, 'push');
            app.Step2Replay.ButtonPushedFcn = createCallbackFcn(app, @Step2ReplayButtonPushed, true);
            app.Step2Replay.Text = '重聽本段';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '設定取樣率';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.Step2Status0 = uilabel(app.UIFigure);
            app.Step2Status0.Text = '尚未開始分析';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = musicAnalysisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end